classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SetPointSlider          matlab.ui.control.Slider
        showSliderValSet        matlab.ui.control.Label
        EndprogramButton        matlab.ui.control.Button
        LogintotxtButton        matlab.ui.control.Button
        SETPOINTrkLabel         matlab.ui.control.Label
        ForwardButton           matlab.ui.control.StateButton
        BackwardButton          matlab.ui.control.StateButton
        Slider_2                matlab.ui.control.Slider
        MANIPULATIONmkLabel     matlab.ui.control.Label
        showSliderValMan        matlab.ui.control.Label
        ControlModeSwitchLabel  matlab.ui.control.Label
        ControlModeSwitch       matlab.ui.control.Switch
        STOPButton              matlab.ui.control.Button
        SetoperationroutineDropDownLabel  matlab.ui.control.Label
        SetOperationRoutine     matlab.ui.control.DropDown
        voltageGraph            matlab.ui.control.UIAxes
        RUNButton               matlab.ui.control.Button
        man_force_plot          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Arduino object
        a;
        % Aplication sample time
        sampleTime = 0.0338;
        ksample = 1;
        
        % operation conditions
        bContinue = true;
        bPause = false;
        windowSamples = 75;
        dControlMode = 'Auto';
        
        % Motor pins
        dForwardPin = 'D10';
        dBackwardPin = 'D11';
        
        % Position potentiometers
        aPositionPin = 'A7';
        aForcePin = 'A8';
        
        % PID and other constants
        Kp = 5.5; %4.061;
        Kd = 0; %171.6;
        Ki = 10; %.8816;
        pseudoSetP = 0.5;
        force_up = 4.0;
        force_down = 2.4;
        
        % for graphing only
        pseudo_man = 0;
        
        % process vectors
        c = [];
        e = [];
        m = [];
        r = [];
        f = [];
        
        % secondary vectors
        force = [];
        
        % Initialized boolean state
        bPIDinit = 0;
  
        
        % waliking stabilisis
        walking_state = 1;
        
        
    end
    
    methods (Access = private)
        
        function results = forward_motor(app,vel)
            writePWMVoltage(app.a,app.dForwardPin,vel);
            writePWMVoltage(app.a,app.dBackwardPin,0);
            
        end
        
        function results = stop_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,0);
        end
        
        function results = back_motor(app,vel)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,vel);
        end
        
        function results = updatePlots(app,YLecture,SetPoint,Error,Manip,Force)
            grid(app.voltageGraph,'on');
            grid(app.man_force_plot,'on');
            
            %%%%% FIRST PLOT %%%%
            plot(app.voltageGraph, YLecture,'--+','MarkerIndices',1:13:length(YLecture),'LineWidth', 2,'color',[0 1 0]);
            % Keep this line here to update new frames
            hold(app.voltageGraph,'on');
            
            plot(app.voltageGraph, SetPoint,'-*','MarkerIndices',1:7:length(YLecture),'LineWidth', 2,'color',[1 0 0]);
            plot(app.voltageGraph, Error,'-x','MarkerIndices',1:11:length(YLecture),'LineWidth', 2,'color',[0 0 1]);
            
            legend(app.voltageGraph,'y(k)','r(k)','e(k)','Location','SouthEast');
            yticks(app.voltageGraph, (-2.5:0.5:2.5));
            
            
            %%%%% SECOND PLOT %%%%
            plot(app.man_force_plot,Manip,'LineWidth', 2,'color',[1 0 0]);
            hold(app.man_force_plot,'on');
            plot(app.man_force_plot,Force,'LineWidth', 2,'color',[0 1 0]);
            legend(app.man_force_plot,'Manipulation','Force','Location','SouthEast');
            yticks(app.man_force_plot, (-5:0.5:5));
            
            drawnow;
        end
        
        function results = initializePID(app)
            cleanVectors(app);
            % Initialization of first samples
            v = readVoltage(app.a,app.aPositionPin);
            T = app.sampleTime;
            fo = readVoltage(app.a,app.aForcePin);
            
            app.r(1) = app.pseudoSetP;
            app.c(1) = v;
            app.e(1) = app.pseudoSetP - app.c(1);
            app.m(1) = (1/(2*T))*((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(1));
            app.f(1) = fo;
            
            app.c(2) = v;
            app.r(2) = app.pseudoSetP;
            app.e(2) = app.pseudoSetP - app.c(2);
            app.m(2) = (1/(2*T))*((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(2) + (2*app.Ki*(T^2)-8*app.Kd)*app.e(1));
            app.f(2) = fo;
        end
        
        function results = runPID(app,k)
            v = readVoltage(app.a,app.aPositionPin);
            T = app.sampleTime;
            fo = readVoltage(app.a,app.aForcePin);
            app.c(k) = v;
            app.r(k) = app.pseudoSetP;
            app.e(k) = app.pseudoSetP - app.c(k);
            app.m(k) = (1/(2*T))*((2*T*app.m(k-2)) + ((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k)) + ((2*app.Ki*(T^2)-8*app.Kd)*app.e(k-1)) + ((-2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k-2)));
            app.f(k) = fo;
            % plot
            app.updatePlots(app.c, app.r,app.e,app.m,app.f);
            
            % Control
            if app.e(k) > 0
                if abs(app.m(k)) > 5
                    writePWMVoltage(app.a, app.dForwardPin, 5);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                elseif abs(app.m(k)) > 2
                    writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                else
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                end
            else
                if abs(app.m(k)) > 5
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 5);
                elseif abs(app.m(k)) > 2
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                else
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%
        % BOUNCER
        %%%%%%%%%%%%%%%%%%%%%%%%%
        function results = runForceAgainst(app,k)

            v = readVoltage(app.a,app.aPositionPin);
            fo = readVoltage(app.a,app.aForcePin);
            T = app.sampleTime;
        
            app.c(k) = v;
            app.r(k) = app.pseudoSetP;
            app.e(k) = app.pseudoSetP - app.c(k);
            app.m(k) = (1/(2*T))*((2*T*app.m(k-2)) + ((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k)) + ((2*app.Ki*(T^2)-8*app.Kd)*app.e(k-1)) + ((-2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k-2)));
            app.f(k) = fo;
            
            app.updatePlots(app.c, app.r,app.e,app.m,app.f);

            % Going down
            if app.e(k) > 0
                if fo > app.force_down && fo < app.force_up % No external force
                    if abs(app.m(k)) > 5
                        writePWMVoltage(app.a, app.dForwardPin, 5);
                        writePWMVoltage(app.a, app.dBackwardPin, 0);
                    elseif abs(app.m(k)) > 2
                        writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                        writePWMVoltage(app.a, app.dBackwardPin, 0);
                    else
                        writePWMVoltage(app.a, app.dForwardPin, 0);
                        writePWMVoltage(app.a, app.dBackwardPin, 0);
                    end
                else % External force
                    if fo > app.force_up % External force going up
                        app.pseudoSetP = 0.5;
                        % for i=k:k+3000
                        %     r(i) = 0.45;
                        % end
                        if abs(app.m(k)) > 5
                            writePWMVoltage(app.a, app.dForwardPin, 0);
                            writePWMVoltage(app.a, app.dBackwardPin, 5);
                        elseif abs(app.m(k)) > 2
                            writePWMVoltage(app.a, app.dForwardPin, 0);
                            writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                        else
                            writePWMVoltage(app.a, app.dForwardPin, 0);
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        end
                    end
                end
            else
                if fo > app.force_down && fo < app.force_up % No external force
                    if abs(app.m(k)) > 5
                        writePWMVoltage(app.a, app.dForwardPin, 0);
                        writePWMVoltage(app.a, app.dBackwardPin, 5);
                    elseif abs(app.m(k)) > 2
                        writePWMVoltage(app.a, app.dForwardPin, 0);
                        writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                    else
                        writePWMVoltage(app.a, app.dForwardPin, 0);
                        writePWMVoltage(app.a, app.dBackwardPin, 0);
                    end
                else % External force
                     if fo < app.force_down          % External force going down
                        app.pseudoSetP = 1.4;
                        % for i=k+1:k+3000
                        %     r(i) = 1.4;
                        % end
                        if abs(app.m(k)) > 5
                            writePWMVoltage(app.a, app.dForwardPin, 5);
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        elseif abs(app.m(k)) > 2
                            writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        else
                            writePWMVoltage(app.a, app.dForwardPin, 0);
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        end
                    end
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%
        % STABILIZATION, WAITER
        %%%%%%%%%%%%%%%%%%%%%%%%%
        function results = runStabilization(app,k)
             
            % STATE 1 GOING DOWN
            if(app.walking_state == 1)
                state = 'state 1'
                app.pseudoSetP = 1.4;
                v = readVoltage(app.a,app.aPositionPin);                
                while (v < 1.25 || v > 1.5) %k=3:1:N
                    loop = 'loop 1'
                    v = readVoltage(app.a,app.aPositionPin);
                    
                    T = app.sampleTime;
                    fo = readVoltage(app.a,app.aForcePin);
                    
                    app.r(k) = app.pseudoSetP;
                    app.c(k) = v;
                    app.e(k) = app.r(k)-app.c(k);
                    app.m(k) = (1/(2*T))*((2*T*app.m(k-2)) + ((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k)) + ((2*app.Ki*(T^2)-8*app.Kd)*app.e(k-1)) + ((-2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k-2)));
                    app.f(k) = fo;
                    
                    app.updatePlots(app.c, app.r,app.e,app.m,app.f);
                    
                    if app.e(k) > 0
                        if fo > app.force_down && fo < app.force_up % No external force
                            if abs(app.m(k)) > 5
                                writePWMVoltage(app.a, app.dForwardPin, 5);
                                writePWMVoltage(app.a, app.dBackwardPin, 0);
                            elseif abs(app.m(k)) > 3
                                writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                                writePWMVoltage(app.a, app.dBackwardPin, 0);
                            else
                                writePWMVoltage(app.a, app.dForwardPin, 3);
                                writePWMVoltage(app.a, app.dBackwardPin, 0);
                            end
                        else
                             if fo > app.force_up % External force going up, then stop
                                stop_motor(app);
                                'force'
                                pause(2);
                            end
                        end
                    else
                            if abs(app.m(k)) > 5
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, 5);
                            elseif abs(app.m(k)) > 3
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                            else
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, 3);
                            end
                    end
                    app.ksample = app.ksample + 1;
                    k = app.ksample;
                end
                stop_motor(app);
                app.walking_state = 2;
            
                % STATE 2  GOING UP  
            elseif(app.walking_state ==2)
                %   
                state = 'state 2'
                app.pseudoSetP = 0.5;
                v = readVoltage(app.a,app.aPositionPin);
                while( v > .65 || v < 0.4)
                    loop = 'loop 2'
                    v = readVoltage(app.a,app.aPositionPin);
                    
                    T = app.sampleTime;
                    fo = readVoltage(app.a,app.aForcePin);
                    
                    app.c(k) = v;
                    app.r(k) = app.pseudoSetP;
                    app.e(k) = app.r(k)-app.c(k);
                    app.m(k) = (1/(2*T))*((2*T*app.m(k-2)) + ((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k)) + ((2*app.Ki*(T^2)-8*app.Kd)*app.e(k-1)) + ((-2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k-2)));
                    app.f(k) = fo;
                    
                    app.updatePlots(app.c, app.r,app.e,app.m,app.f);
                    
                    if app.e(k) > 0
                        if abs(app.m(k)) > 5
                            writePWMVoltage(app.a, app.dForwardPin, 5);
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        elseif abs(app.m(k))  > 3
                            writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        else
                            writePWMVoltage(app.a, app.dForwardPin, 3);
                            writePWMVoltage(app.a, app.dBackwardPin, 0);
                        end
                    else
                        if fo > app.force_down && fo < app.force_up % No external force
                            if abs(app.m(k)) > 5
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, 5);
                            elseif abs(app.m(k)) > 3
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                            else
                                writePWMVoltage(app.a, app.dForwardPin, 0);
                                writePWMVoltage(app.a, app.dBackwardPin, 3);
                            end
                        else
                             if fo < app.force_down       % External force going down, stop                                 
                                stop_motor(app);
                                pause(2);
                                
                            end
                        end
                    end
                    app.ksample = app.ksample + 1;
                    k = app.ksample;
                end
                stop_motor(app);
                app.walking_state = 1;
            end
        end
        
        function results = cleanVectors(app)
            app.r = [];
            app.c = [];
            app.e = [];
            app.m = [];
            app.f = [];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialice ARDUINO object
            pause(2);
            % resize app window
            
            app.a = arduino('/dev/cu.usbmodem1421','Mega2560');
            % app.a = arduino();
            
            % Defining matrix
            lectures = [];
            setPoint = [];
            err = [];
            man = [];
            forze = [];
            app.ksample = 1;
            
            
            % Setting constraints limits
            app.voltageGraph.XLim = [0 app.windowSamples];
            app.voltageGraph.YLim = [-2.3 2.5];
            app.man_force_plot.XLim = [0 app.windowSamples];
            app.man_force_plot.YLim = [-5 5];
            
            % Aplication loop
            while(app.bContinue)
                if(~app.bPause)
                    % clear view
                    if(app.ksample > app.windowSamples)
                        lectures = [];
                        setPoint = [];
                        err = [];
                        man = [];
                        forze = [];
                        
                        app.c = [];
                        app.e = [];
                        app.m = [];
                        app.r = [];
                        app.f = [];
                        
                        app.ksample = 1;
                        app.bPIDinit = 0;
                        hold(app.voltageGraph,'off');
                        hold(app.man_force_plot,'off');
          
                    end
                    
                    % -------------- %
                    % OPERATION MODE %
                    % -------------- %
                    
                    % MANUAL
                    if(strcmp(app.dControlMode,'Manual'))
                        v = app.a.readVoltage(app.aPositionPin);
                        fo = readVoltage(app.a,app.aForcePin);
                        
                        lectures = [lectures v];
                        setPoint = [setPoint app.SetPointSlider.Value];
                        err = [err, (v-app.SetPointSlider.Value)];
                        man = [man app.pseudo_man];
                        
                        forze = [forze fo];
                        % plot
                        app.updatePlots(lectures,setPoint,err,man,forze);


                        % AUTOMATIC
                    elseif(strcmp(app.dControlMode,'Auto'))
                        routine = app.SetOperationRoutine.Value;
                        
                        % Normal Set Point
                        if(strcmp(routine,'Reach Setpoint'))
                            if(app.bPIDinit == 0)
                                app.initializePID();
                                app.bPIDinit = 1;
                                app.ksample = 2;
                            else
                                app.runPID(app.ksample);
                            end
                                                    
                        % Force Against  
                        elseif(strcmp(routine,'bounce'))
                            if(app.bPIDinit == 0)
                                app.initializePID();
                                app.bPIDinit = 1;
                                app.ksample = 2;
                                
                                
                            else
                                app.runForceAgainst(app.ksample);
                            end
                        % Force Asist        
                        elseif(strcmp(routine,'wait')) 
                            if(app.bPIDinit == 0)
                                app.initializePID();
                                app.bPIDinit = 1;
                                app.pseudoSetP = 1.4;
                                app.ksample = 2;
                            else
                                s = 'inside'
                                app.runStabilization(app.ksample);
                            end
                        end                    
                    end
                    app.ksample = app.ksample + 1;

                end
                % Time to plot and interrupt
                pause(0.0001);
            end


        end

        % Value changed function: SetPointSlider
        function SetPointSliderValueChanged(app, event)
            sliderValue = app.SetPointSlider.Value;
            
            % update setpoint
            if(app.SetOperationRoutine.Value == 'Reach Setpoint')
                app.pseudoSetP = sliderValue;
            end
            
            % show value
            app.showSliderValSet.Text = num2str(sliderValue);
            
        end

        % Button pushed function: EndprogramButton
        function EndprogramButtonPushed(app, event)
            app.bContinue = false;
        end

        % Value changed function: ForwardButton
        function ForwardButtonValueChanged(app, event)
            value = app.ForwardButton.Value;
            if(value == true)
                app.ForwardButton.Text = 'STOP';
                forward_motor(app,5);
                app.pseudo_man = 5;
            else
                app.ForwardButton.Text = 'Forward';
                stop_motor(app);
                app.pseudo_man = 0;
            end
        end

        % Value changed function: BackwardButton
        function BackwardButtonValueChanged(app, event)
            value = app.BackwardButton.Value;
            if(value == true)
                app.BackwardButton.Text = 'STOP';
                back_motor(app,5);
                app.pseudo_man = -5;
            else
                app.BackwardButton.Text = 'Backward';
                stop_motor(app);
                app.pseudo_man = 0;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop_motor(app);
        end

        % Value changed function: ControlModeSwitch
        function ControlModeSwitchValueChanged(app, event)
            value = app.ControlModeSwitch.Value;
            app.dControlMode = value;
            app.bPIDinit = 0;
            hold(app.voltageGraph,'off');
            hold(app.man_force_plot,'off');
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            if(app.bPause)
                app.bPause = false;
                app.RUNButton.Text = 'Pause';
            else
                app.bPause = true;
                app.RUNButton.Text = 'Continue';
                stop_motor(app);
            end
        end

        % Value changed function: SetOperationRoutine
        function SetOperationRoutineValueChanged(app, event)
            value = app.SetOperationRoutine.Value;
            app.bPIDinit = 0;
            hold(app.voltageGraph,'off');
            hold(app.man_force_plot,'off');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetPointSlider = uislider(app.UIFigure);
            app.SetPointSlider.Limits = [0.44 1.4];
            app.SetPointSlider.MajorTicks = [0.44 0.54 0.64 0.74 0.84 0.94 1.04 1.14 1.24 1.4];
            app.SetPointSlider.MajorTickLabels = {'min', '0.54', '0.64', '0.74', '0.84', '0.94', '1.04', '1.14', '1.24', 'max'};
            app.SetPointSlider.ValueChangedFcn = createCallbackFcn(app, @SetPointSliderValueChanged, true);
            app.SetPointSlider.Value = 0.44;
            app.showSliderValSet = uilabel(app.UIFigure);
            app.showSliderValSet.Text = '';
            app.EndprogramButton = uibutton(app.UIFigure, 'push');
            app.EndprogramButton.ButtonPushedFcn = createCallbackFcn(app, @EndprogramButtonPushed, true);
            app.EndprogramButton.Text = 'End program';
            app.LogintotxtButton = uibutton(app.UIFigure, 'push');
            app.LogintotxtButton.Text = 'Log into txt';
            app.SETPOINTrkLabel = uilabel(app.UIFigure);
            app.SETPOINTrkLabel.Text = {'SET POINT '; 'r(k)'};
            app.ForwardButton = uibutton(app.UIFigure, 'state');
            app.ForwardButton.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonValueChanged, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'state');
            app.BackwardButton.ValueChangedFcn = createCallbackFcn(app, @BackwardButtonValueChanged, true);
            app.BackwardButton.Text = 'Backward';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 5];
            app.Slider_2.Value = 1;
            app.MANIPULATIONmkLabel = uilabel(app.UIFigure);
            app.MANIPULATIONmkLabel.Text = {'MANIPULATION'; 'm(k)'};
            app.showSliderValMan = uilabel(app.UIFigure);
            app.showSliderValMan.Text = '';
            app.ControlModeSwitchLabel = uilabel(app.UIFigure);
            app.ControlModeSwitchLabel.Text = 'Control Mode';
            app.ControlModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ControlModeSwitch.Items = {'Manual', 'Auto'};
            app.ControlModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlModeSwitchValueChanged, true);
            app.ControlModeSwitch.Value = 'Auto';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = '¡ STOP !';
            app.SetoperationroutineDropDownLabel = uilabel(app.UIFigure);
            app.SetoperationroutineDropDownLabel.Text = 'Set operation routine';
            app.SetOperationRoutine = uidropdown(app.UIFigure);
            app.SetOperationRoutine.Items = {'wait', 'bounce', 'Reach Setpoint'};
            app.SetOperationRoutine.ValueChangedFcn = createCallbackFcn(app, @SetOperationRoutineValueChanged, true);
            app.SetOperationRoutine.Value = 'Reach Setpoint';
            app.voltageGraph = uiaxes(app.UIFigure);
            title(app.voltageGraph, 'Control Ouput')
            xlabel(app.voltageGraph, 'sample(k)')
            ylabel(app.voltageGraph, 'Voltage')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.man_force_plot = uiaxes(app.UIFigure);
            title(app.man_force_plot, 'Title')
            xlabel(app.man_force_plot, 'X')
            ylabel(app.man_force_plot, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end